classdef Equalizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Filter_ResumeButton           matlab.ui.control.Button
        Ori_ResumeButton              matlab.ui.control.Button
        HighSliderLabel               matlab.ui.control.Label
        Filter_StopButton             matlab.ui.control.Button
        Filter_PauseButton            matlab.ui.control.Button
        Filter_PlayButton             matlab.ui.control.Button
        Ori_StopButton                matlab.ui.control.Button
        Ori_PauseButton               matlab.ui.control.Button
        Ori_PlayButton                matlab.ui.control.Button
        ExportButton                  matlab.ui.control.Button
        MATLABGUIAUDIOEQUALIZERLabel  matlab.ui.control.Label
        BrowseButton                  matlab.ui.control.Button
        FileNameArea                  matlab.ui.control.TextArea
        Label                         matlab.ui.control.Label
        SuperLowSlider                matlab.ui.control.Slider
        LowSlider                     matlab.ui.control.Slider
        MidrangeSlider                matlab.ui.control.Slider
        UpperMidrangeSlider           matlab.ui.control.Slider
        UpperMidrangeSliderLabel      matlab.ui.control.Label
        Band24kHzLabel                matlab.ui.control.Label
        LowMidrangeSlider             matlab.ui.control.Slider
        Band052kHzLabel               matlab.ui.control.Label
        Band250500HzLabel             matlab.ui.control.Label
        HighSlider                    matlab.ui.control.Slider
        SuperLowEditField             matlab.ui.control.NumericEditField
        LowEditField                  matlab.ui.control.NumericEditField
        LowMidEditField               matlab.ui.control.NumericEditField
        MidEditField                  matlab.ui.control.NumericEditField
        UpMidEditField                matlab.ui.control.NumericEditField
        HighEditField                 matlab.ui.control.NumericEditField
        Low250HzLabel                 matlab.ui.control.Label
        Filter_Axes                   matlab.ui.control.UIAxes
        Original_Axes                 matlab.ui.control.UIAxes
        Sliderform_Axes               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        file ;
        path ;
        ori_player ;
        filter_player ;
        y1 ;
        Fs1 ;
        yF ;
        FsF ;
        browse = "no" ;
        ori_state = "0" ;
        filter_state = "0" ;
        SuperLow_Gain = 1 ;
        Low_Gain = 1 ;
        LowerMid_Gain = 1 ;
        Mid_Gain = 1 ;
        UpperMid_Gain = 1 ;
        High_Gain = 1 ;

        yx2 ;
        yx3 ;
        yx4 ;
        yx5 ;
        yx6 ;
        yx7 ;

    end
    
    methods (Access = public)
        
        function DrawOutput(app)
           
            if app.browse == "yes"
                h2=fir1(16,[20/(app.Fs1/2) 60/(app.Fs1/2)],'low'); 
                app.yx2=app.SuperLow_Gain*filter(h2,1,app.y1); 
            

                h3=fir1(16,[61/(app.Fs1/2) 200/(app.Fs1/2)],'bandpass');
                app.yx3=app.Low_Gain*filter(h3,1,app.y1); 

                h4=fir1(16,[201/(app.Fs1/2) 600/(app.Fs1/2)],'bandpass'); 
                app.yx4=app.LowerMid_Gain*filter(h4,1,app.y1); 

                h5=fir1(16,[601/(app.Fs1/2) 3000/(app.Fs1/2)],'bandpass');
                app.yx5=app.Mid_Gain*filter(h5,1,app.y1); 

                h6=fir1(16,[3001/(app.Fs1/2) 8000/(app.Fs1/2)],'bandpass');
                app.yx6=app.UpperMid_Gain*filter(h6,1,app.y1); 

                h7=fir1(16,8001/(app.Fs1/2),'high'); 
                app.yx7=app.High_Gain*filter(h7,1,app.y1); 


                app.yF= app.yx2 + app.yx3 + app.yx4 + app.yx5 + app.yx6 + app.yx7 ;
                app.filter_player = audioplayer(app.yF, app.Fs1);
       
      
                plot(app.Filter_Axes, app.yF);
            end
           
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Gainchange(app)
            x = [1 2 3 4 5 6] ;
            y = [app.SuperLow_Gain app.Low_Gain app.LowerMid_Gain app.Mid_Gain app.UpperMid_Gain app.High_Gain] ;
         
            plot(app.Sliderform_Axes,x,y,'-o','LineWidth',2,'Color',"#000000");
        end

        % Callback function: BrowseButton, FileNameArea
        function BrowseButtonPushed(app, event)

            [app.file,app.path] = uigetfile({'*.wav'},'Select file .wav');
            app.FileNameArea.Value = app.file;
            app.browse = "yes" ;
            [app.y1, app.Fs1] = audioread(fullfile(app.path,app.file));
            app.ori_player = audioplayer(app.y1, app.Fs1);


            %app.FileNameArea.Value = num2str(Fs1);        % Display sample rate in label
            plot(app.Original_Axes, app.y1);
           
            Gainchange(app)
            DrawOutput(app)
        end

        % Button pushed function: Ori_PlayButton
        function Ori_PlayButtonPushed(app, event)
            play(app.ori_player) 

    
        end

        % Button pushed function: Ori_PauseButton
        function Ori_PauseButtonPushed(app, event)

           pause(app.ori_player)
           app.ori_state = "1" ;
        end

        % Button pushed function: Ori_ResumeButton
        function Ori_ResumeButtonPushed(app, event)
           if app.ori_state == "1"
            resume(app.ori_player)
            app.ori_state = "0" ;
           end
        end

        % Button pushed function: Ori_StopButton
        function Ori_StopButtonPushed(app, event)
            stop(app.ori_player) 

        end

        % Button pushed function: Filter_PlayButton
        function Filter_PlayButtonPushed(app, event)
            play(app.filter_player) 

        end

        % Button pushed function: Filter_PauseButton
        function Filter_PauseButtonPushed(app, event)

           pause(app.filter_player)
           app.filter_state = "1" ;
        end

        % Button pushed function: Filter_ResumeButton
        function Filter_ResumeButtonPushed(app, event)
           if app.filter_state == "1" 
            resume(app.filter_player)
            app.filter_state = "0" ;
           end
        end

        % Button pushed function: Filter_StopButton
        function Filter_StopButtonPushed(app, event)
            stop(app.filter_player) 

        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            [app.file,app.path] = uiputfile({'*.wav'},'Save As');
            newfile = fullfile(app.path,app.file) ;
            audiowrite(newfile,app.yF,app.Fs1);
        end

        % Value changed function: HighSlider, LowMidrangeSlider, 
        % ...and 4 other components
        function SuperLowSliderValueChanged(app, event)
           app.SuperLow_Gain = app.SuperLowSlider.Value;
           app.Low_Gain = app.LowSlider.Value;
           app.LowerMid_Gain = app.LowMidrangeSlider.Value;
           app.Mid_Gain = app.MidrangeSlider.Value;
           app.UpperMid_Gain = app.UpperMidrangeSlider.Value;
           app.High_Gain = app.HighSlider.Value;

           app.SuperLowEditField.Value = app.SuperLow_Gain;
           app.LowEditField.Value = app.Low_Gain;
           app.LowMidEditField.Value = app.LowerMid_Gain;
           app.MidEditField.Value = app.Mid_Gain;
           app.UpMidEditField.Value = app.UpperMid_Gain;
           app.HighEditField.Value = app.High_Gain;
           Gainchange(app)
           DrawOutput(app)


        end

        % Value changed function: HighEditField, LowEditField, 
        % ...and 4 other components
        function SuperLowEditFieldValueChanged(app, event)
           app.SuperLow_Gain = app.SuperLowEditField.Value; 
           app.Low_Gain = app.LowEditField.Value;
           app.LowerMid_Gain = app.LowMidEditField.Value;
           app.Mid_Gain = app.MidEditField.Value;
           app.UpperMid_Gain = app.UpMidEditField.Value;
           app.High_Gain = app.HighEditField.Value;
           
           app.SuperLowSlider.Value = app.SuperLow_Gain;
           app.LowSlider.Value = app.Low_Gain;
           app.LowMidrangeSlider.Value = app.LowerMid_Gain;
           app.MidrangeSlider.Value = app.Mid_Gain;
           app.UpperMidrangeSlider.Value = app.UpperMid_Gain;
           app.HighSlider.Value = app.High_Gain;
           Gainchange(app)
           DrawOutput(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Sliderform_Axes = uiaxes(app.UIFigure);
            title(app.Sliderform_Axes, ' ')
            ylabel(app.Sliderform_Axes, 'Magnitude')
            app.Sliderform_Axes.YLim = [-12 12];
            app.Sliderform_Axes.XTick = [];
            app.Sliderform_Axes.YTick = [-18 -12 -6 0 6 12 18];
            app.Sliderform_Axes.YTickLabel = {'-18'; '-12'; '-6'; '0'; '6'; '12'; '18'};
            app.Original_Axes = uiaxes(app.UIFigure);
            title(app.Original_Axes, 'Original Waveform')
            xlabel(app.Original_Axes, 'Time')
            ylabel(app.Original_Axes, 'Magnitude')
            app.Filter_Axes = uiaxes(app.UIFigure);
            title(app.Filter_Axes, 'Filter Waveform')
            xlabel(app.Filter_Axes, 'Time')
            ylabel(app.Filter_Axes, 'Magnitude')
            app.Low250HzLabel = uilabel(app.UIFigure);
            app.Low250HzLabel.Text = 'Super Low';
            app.HighEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighEditField.ValueChangedFcn = createCallbackFcn(app, @SuperLowEditFieldValueChanged, true);
            app.HighEditField.Value = 1;
            app.UpMidEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpMidEditField.ValueChangedFcn = createCallbackFcn(app, @SuperLowEditFieldValueChanged, true);
            app.UpMidEditField.Value = 1;
            app.MidEditField = uieditfield(app.UIFigure, 'numeric');
            app.MidEditField.ValueChangedFcn = createCallbackFcn(app, @SuperLowEditFieldValueChanged, true);
            app.MidEditField.Value = 1;
            app.LowMidEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowMidEditField.ValueChangedFcn = createCallbackFcn(app, @SuperLowEditFieldValueChanged, true);
            app.LowMidEditField.Value = 1;
            app.LowEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowEditField.ValueChangedFcn = createCallbackFcn(app, @SuperLowEditFieldValueChanged, true);
            app.LowEditField.Value = 1;
            app.SuperLowEditField = uieditfield(app.UIFigure, 'numeric');
            app.SuperLowEditField.ValueChangedFcn = createCallbackFcn(app, @SuperLowEditFieldValueChanged, true);
            app.SuperLowEditField.Value = 1;
            app.HighSlider = uislider(app.UIFigure);
            app.HighSlider.Limits = [-12 12];
            app.HighSlider.MajorTicks = [];
            app.HighSlider.ValueChangedFcn = createCallbackFcn(app, @SuperLowSliderValueChanged, true);
            app.HighSlider.MinorTicks = [];
            app.Band250500HzLabel = uilabel(app.UIFigure);
            app.Band250500HzLabel.Text = 'Low';
            app.Band052kHzLabel = uilabel(app.UIFigure);
            app.Band052kHzLabel.Text = 'Low Mid-range';
            app.LowMidrangeSlider = uislider(app.UIFigure);
            app.LowMidrangeSlider.Limits = [-12 12];
            app.LowMidrangeSlider.MajorTicks = [];
            app.LowMidrangeSlider.ValueChangedFcn = createCallbackFcn(app, @SuperLowSliderValueChanged, true);
            app.LowMidrangeSlider.MinorTicks = [];
            app.Band24kHzLabel = uilabel(app.UIFigure);
            app.Band24kHzLabel.Text = 'Mid-range';
            app.UpperMidrangeSliderLabel = uilabel(app.UIFigure);
            app.UpperMidrangeSliderLabel.Text = 'Upper Mid-range';
            app.UpperMidrangeSlider = uislider(app.UIFigure);
            app.UpperMidrangeSlider.Limits = [-12 12];
            app.UpperMidrangeSlider.MajorTicks = [];
            app.UpperMidrangeSlider.ValueChangedFcn = createCallbackFcn(app, @SuperLowSliderValueChanged, true);
            app.UpperMidrangeSlider.MinorTicks = [];
            app.MidrangeSlider = uislider(app.UIFigure);
            app.MidrangeSlider.Limits = [-12 12];
            app.MidrangeSlider.MajorTicks = [];
            app.MidrangeSlider.ValueChangedFcn = createCallbackFcn(app, @SuperLowSliderValueChanged, true);
            app.MidrangeSlider.MinorTicks = [];
            app.LowSlider = uislider(app.UIFigure);
            app.LowSlider.Limits = [-12 12];
            app.LowSlider.MajorTicks = [];
            app.LowSlider.ValueChangedFcn = createCallbackFcn(app, @SuperLowSliderValueChanged, true);
            app.LowSlider.MinorTicks = [];
            app.SuperLowSlider = uislider(app.UIFigure);
            app.SuperLowSlider.Limits = [-12 12];
            app.SuperLowSlider.MajorTicks = [];
            app.SuperLowSlider.MajorTickLabels = {''};
            app.SuperLowSlider.ValueChangedFcn = createCallbackFcn(app, @SuperLowSliderValueChanged, true);
            app.SuperLowSlider.MinorTicks = [];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.FileNameArea = uitextarea(app.UIFigure);
            app.FileNameArea.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.MATLABGUIAUDIOEQUALIZERLabel = uilabel(app.UIFigure);
            app.MATLABGUIAUDIOEQUALIZERLabel.Text = 'MATLAB GUI AUDIO EQUALIZER';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.Ori_PlayButton = uibutton(app.UIFigure, 'push');
            app.Ori_PlayButton.ButtonPushedFcn = createCallbackFcn(app, @Ori_PlayButtonPushed, true);
            app.Ori_PlayButton.Text = 'Play';
            app.Ori_PauseButton = uibutton(app.UIFigure, 'push');
            app.Ori_PauseButton.ButtonPushedFcn = createCallbackFcn(app, @Ori_PauseButtonPushed, true);
            app.Ori_PauseButton.Text = 'Pause';
            app.Ori_StopButton = uibutton(app.UIFigure, 'push');
            app.Ori_StopButton.ButtonPushedFcn = createCallbackFcn(app, @Ori_StopButtonPushed, true);
            app.Ori_StopButton.Text = 'Stop';
            app.Filter_PlayButton = uibutton(app.UIFigure, 'push');
            app.Filter_PlayButton.ButtonPushedFcn = createCallbackFcn(app, @Filter_PlayButtonPushed, true);
            app.Filter_PlayButton.Text = 'Play';
            app.Filter_PauseButton = uibutton(app.UIFigure, 'push');
            app.Filter_PauseButton.ButtonPushedFcn = createCallbackFcn(app, @Filter_PauseButtonPushed, true);
            app.Filter_PauseButton.Text = 'Pause';
            app.Filter_StopButton = uibutton(app.UIFigure, 'push');
            app.Filter_StopButton.ButtonPushedFcn = createCallbackFcn(app, @Filter_StopButtonPushed, true);
            app.Filter_StopButton.Text = 'Stop';
            app.HighSliderLabel = uilabel(app.UIFigure);
            app.HighSliderLabel.Text = 'High';
            app.Ori_ResumeButton = uibutton(app.UIFigure, 'push');
            app.Ori_ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @Ori_ResumeButtonPushed, true);
            app.Ori_ResumeButton.Text = 'Resume';
            app.Filter_ResumeButton = uibutton(app.UIFigure, 'push');
            app.Filter_ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @Filter_ResumeButtonPushed, true);
            app.Filter_ResumeButton.Text = 'Resume';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Equalizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Gainchange)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end